classdef OAS_Behavior_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        window                matlab.ui.control.EditField
        TimeprepostLabel      matlab.ui.control.Label
        exportTiffButton      matlab.ui.control.Button
        generateTracksButton  matlab.ui.control.Button
        setPathButton         matlab.ui.control.Button
        path                  matlab.ui.control.EditField
        ax                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: setPathButton
        function setPathButtonPushed(app, event)
            previous = app.path.Value;
            current = uigetdir(previous);
            app.path.Value = current;
        end

        % Button pushed function: exportTiffButton
        function exportTiffButtonPushed(app, event)

            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            enableDefaultInteractivity(app.ax);

            [xPt,yPt] = ginput(1);
            % Get (x,y) coordinates for all points
            h = gco();
            app.UIFigure.HandleVisibility = fhv; 
            hx = h.XData;
            hy = h.YData;
            hz = h.ZData;
            % Find the nearest point to selection
            d = sqrt((xPt-hx).^2 + (yPt-hy).^2);
            [~,minIdx] = min(d);

            timepoint = hz(minIdx);


            txt = input(['Save video at time: ' num2str(timepoint/15/60) ' min? (y/n)...'],"s");
            if strcmp(txt,'y')
                makeVideoFromTimepoints(app.path.Value, timepoint, str2double(app.window.value))
            end
        end

        % Button pushed function: generateTracksButton
        function generateTracksButtonPushed(app, event)
            
            makeTrackMontage(app.path.Value, app.ax)
            [tb,btns] = axtoolbar(app.ax,{'default'});
            view(app.ax, 0, 90)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax = uiaxes(app.UIFigure);
            xlabel(app.ax, 'X')
            ylabel(app.ax, 'Y')
            zlabel(app.ax, 'Z')
            app.path = uieditfield(app.UIFigure, 'text');
            app.path.Value = 'C:\Users\Jeremy\Desktop\2024_1_30_wt_2\2024_01_30_11_30_45_flircamera_behavior';
            app.setPathButton = uibutton(app.UIFigure, 'push');
            app.setPathButton.ButtonPushedFcn = createCallbackFcn(app, @setPathButtonPushed, true);
            app.setPathButton.Text = 'Set Path';
            app.generateTracksButton = uibutton(app.UIFigure, 'push');
            app.generateTracksButton.ButtonPushedFcn = createCallbackFcn(app, @generateTracksButtonPushed, true);
            app.generateTracksButton.Text = {'Generate'; 'Tracks'};
            app.exportTiffButton = uibutton(app.UIFigure, 'push');
            app.exportTiffButton.ButtonPushedFcn = createCallbackFcn(app, @exportTiffButtonPushed, true);
            app.exportTiffButton.Text = {'Export'; 'Tiff'};
            app.TimeprepostLabel = uilabel(app.UIFigure);
            app.TimeprepostLabel.Text = {'Time'; 'pre/post'};
            app.window = uieditfield(app.UIFigure, 'text');
            app.window.Value = '30';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OAS_Behavior_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end